classdef iCareApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        iCARELabel                    matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BlinkrateTab                  matlab.ui.container.Tab
        LiveblinkrateEditFieldLabel   matlab.ui.control.Label
        LiveblinkrateEditField        matlab.ui.control.NumericEditField
        BlinksperminuteGaugeLabel     matlab.ui.control.Label
        BlinksperminuteGauge          matlab.ui.control.Gauge
        TotalblinksrecordedEditFieldLabel  matlab.ui.control.Label
        TotalblinksrecordedEditField  matlab.ui.control.NumericEditField
        TimewindowEditFieldLabel      matlab.ui.control.Label
        TimewindowEditField           matlab.ui.control.NumericEditField
        NumberofbuzzesEditFieldLabel  matlab.ui.control.Label
        NumberofbuzzesEditField       matlab.ui.control.NumericEditField
        AmbientUVTab                  matlab.ui.container.Tab
        UVindexGaugeLabel             matlab.ui.control.Label
        UVindexGauge                  matlab.ui.control.LinearGauge
        ImagingTab                    matlab.ui.container.Tab
        BrowseButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        MonitorallButton              matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        AD % arduino object
        States = struct % button states
        Parameters = struct
        Data = struct
        MySound
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AD = arduino();
            disp(app.AD)
            app.States.All = 0;
            app.States.BlinkMonitor = 0;
            app.States.UVMonitor = 0;
            app.Parameters.dt = 0.1;
            app.Parameters.N = 300;
            app.Parameters.BuzzerStuff = struct;
            app.Parameters.BuzzerStuff.blinkRateThreshold = 10;
            app.Parameters.BuzzerStuff.waitTime = 10; % s
            app.Parameters.BuzzerStuff.timerRestart = true;
            app.MySound = load('gong.mat');
            imshow(rand([10,10]), 'Parent', app.UIAxes)
        end

        % Value changed function: MonitorallButton
        function MonitorallButtonValueChanged(app, event)
            value = app.MonitorallButton.Value;
            app.States.All = value;
            dt = app.Parameters.dt;
            N = app.Parameters.N;
            S = 5 * ones(N,1);
            
            S = CircArray(S);
            
            thresholdVal = 2.0; % Change to Volts
            numConsecVals = 1;
            trackBlinks = CircArray(zeros(N,1));
            
            timeTrackingWindow = 30; % seconds
            slowingFactor = 2;
            numTimeSteps = round(timeTrackingWindow/(slowingFactor*dt)); % note these will be only approximately realized
            
            % trackTime = CircArray(zeros(numTimeSteps,1));
            trackTime = CircArray((dt*(-numTimeSteps+1:0).'));
            
            liveWindowSize = 0;
            liveBlinkRate = 0;
            
            counter = 0;
            blinkCounter = 0;
            numTrack = 0;
            
            tic;
            while app.States.All
                % take data
                pause(dt)
                tNow = toc;
                numTrack = numTrack + 1;
                trackTime.WriteVals(1, tNow);
                newInput = app.AD.readVoltage('A0');
                % newInput = 5 + 0.1*randn(1) - 4*(rand(1)>0.9); % state as assumption
                S.WriteVals(1, newInput);


                if S.GetVals(0) < thresholdVal
                    counter = counter + 1;
                    trackBlinks.WriteVals(1,0);
                else
                    if counter >= numConsecVals
                        trackBlinks.WriteVals(1,1);
                        blinkCounter = blinkCounter + 1;
                        %disp(['Blinks: ' num2str(blinkCounter)])
                        app.TotalblinksrecordedEditField.Value = blinkCounter;
                    else
                        trackBlinks.WriteVals(1,0);
                    end
                    counter = 0;
                end
               
                if numTrack < numTimeSteps
                    liveWindowSize = trackTime.GetVals(0) - trackTime.GetVals(-numTrack+1);
                    liveBlinkRate = 60*sum(trackBlinks.GetVals((-numTrack+1):0))/liveWindowSize;
                    
                else
                    liveWindowSize = trackTime.GetVals(0) - trackTime.GetVals(-numTimeSteps+1);
                    liveBlinkRate = 60*sum(trackBlinks.GetVals((-numTimeSteps+1):0))/liveWindowSize;
                end
                
                if numTrack > 4
                    %liveWindowSize = trackTime.GetVals(0) - trackTime.GetVals(-numTimeSteps+1);
                    app.TimewindowEditField.Value = liveWindowSize;
                    %liveBlinkRate = 60*sum(trackBlinks.GetVals((-numTimeSteps+1):0))/liveWindowSize(end);
                    app.LiveblinkrateEditField.Value = liveBlinkRate;
                    app.BlinksperminuteGauge.Value = liveBlinkRate;
                end
                    
                    % Buzzer
                if liveBlinkRate < app.Parameters.BuzzerStuff.blinkRateThreshold
                    if app.Parameters.BuzzerStuff.timerRestart
                        startTime = toc;
                    end
                    
                    checkTime = toc - startTime;
                    if checkTime > app.Parameters.BuzzerStuff.waitTime
                        disp('BUZZZZZZZZZZZZ')
                        sound(app.MySound.y)
                        app.NumberofbuzzesEditField.Value = ...
                            app.NumberofbuzzesEditField.Value + 1;
                        app.Parameters.BuzzerStuff.timerRestart = true;
                    else
                        app.Parameters.BuzzerStuff.timerRestart = false;
                    end
                    
                else
                    app.Parameters.BuzzerStuff.timerRestart = true;
                    
                end
                
            end


%             app.States.All = app.MonitorallButton.Value;
%             disp(app.MonitorallButton.Value)
%             
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fileName, path] = uigetfile;
            fullFileName = fullfile(path, fileName);
            loadedImage = imread(fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.iCARELabel = uilabel(app.UIFigure);
            app.iCARELabel.Text = 'iCARE';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BlinkrateTab = uitab(app.TabGroup);
            app.BlinkrateTab.Title = 'Blink rate';
            app.LiveblinkrateEditFieldLabel = uilabel(app.BlinkrateTab);
            app.LiveblinkrateEditFieldLabel.Text = 'Live blink rate';
            app.LiveblinkrateEditField = uieditfield(app.BlinkrateTab, 'numeric');
            app.BlinksperminuteGaugeLabel = uilabel(app.BlinkrateTab);
            app.BlinksperminuteGaugeLabel.Text = 'Blinks per minute';
            app.BlinksperminuteGauge = uigauge(app.BlinkrateTab, 'circular');
            app.TotalblinksrecordedEditFieldLabel = uilabel(app.BlinkrateTab);
            app.TotalblinksrecordedEditFieldLabel.Text = 'Total blinks recorded';
            app.TotalblinksrecordedEditField = uieditfield(app.BlinkrateTab, 'numeric');
            app.TimewindowEditFieldLabel = uilabel(app.BlinkrateTab);
            app.TimewindowEditFieldLabel.Text = 'Time window';
            app.TimewindowEditField = uieditfield(app.BlinkrateTab, 'numeric');
            app.NumberofbuzzesEditFieldLabel = uilabel(app.BlinkrateTab);
            app.NumberofbuzzesEditFieldLabel.Text = 'Number of buzzes';
            app.NumberofbuzzesEditField = uieditfield(app.BlinkrateTab, 'numeric');
            app.AmbientUVTab = uitab(app.TabGroup);
            app.AmbientUVTab.Title = 'Ambient UV';
            app.UVindexGaugeLabel = uilabel(app.AmbientUVTab);
            app.UVindexGaugeLabel.Text = 'UV index';
            app.UVindexGauge = uigauge(app.AmbientUVTab, 'linear');
            app.UVindexGauge.Limits = [0 10];
            app.UVindexGauge.MinorTicks = [];
            app.UVindexGauge.Value = 4;
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.BrowseButton = uibutton(app.ImagingTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UIAxes = uiaxes(app.ImagingTab);
            title(app.UIAxes, 'Show image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MonitorallButton = uibutton(app.UIFigure, 'state');
            app.MonitorallButton.ValueChangedFcn = createCallbackFcn(app, @MonitorallButtonValueChanged, true);
            app.MonitorallButton.Text = 'Monitor all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iCareApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end